classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        Switch_4     matlab.ui.control.RockerSwitch
        Switch_3     matlab.ui.control.RockerSwitch
        TextArea     matlab.ui.control.TextArea
        Label_3      matlab.ui.control.Label
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        Image        matlab.ui.control.Image
        Label        matlab.ui.control.Label
        CenterPanel  matlab.ui.container.Panel
        Panel_2      matlab.ui.container.Panel
        UIAxes2      matlab.ui.control.UIAxes
        Panel        matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
        Button_12    matlab.ui.control.Button
        DropDown     matlab.ui.control.DropDown
        Label_2      matlab.ui.control.Label
        Button_11    matlab.ui.control.Button
        Button_10    matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Image2       matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        music_path = 'C:\Users\晨轩\Documents\MATLAB\Project\musicbase\稻香.mp3'; % Description
        frameSize = 1024; % Description
        audiodata;
        fs;
        audiodata_origin;
        fs_origin;
        audiodata_processed;
        fs_processed;
        player;
        speed = 1;
        matched_music;
    end
    
    properties (Access = public)
        stop_flag = false;
        pause_flag = false;
        origin_flag = false; % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create_mfcc_base(); % 创建mfcc特征库
            % load('mfcc_base.mat'); % 导入特征库
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            app.music_path = fullfile(path,file);
            [app.audiodata,app.fs] = audioread(app.music_path);
            app.audiodata_origin = app.audiodata;
            app.fs_origin = app.fs;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %如果播放器中没有音乐就读入数据，并设置时间
            if isempty(app.player) == 1
                app.player = audioplayer(app.audiodata_origin,app.fs); 
            else
                stop(app.player);
                app.player = audioplayer(app.audiodata_origin,app.fs); 
            end
            %按下播放按钮，图标变化
            if app.Button_2.Text == '播放'
                %判断是播放还是继续
                if app.player.CurrentSample == 1
                    play(app.player);
                elseif app.player.CurrentSample ~= 1
                    resume(app.player);
                end
                app.Button_2.Text = '暂停';
            else
                pause(app.player);
                app.Button_2.Text = '播放';
            end  
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            a = 12;
            A = string(a);
            app.TextArea.Value = A;% 显示正在处理
        
            
            % create_mfcc_base(); % 创建mfcc特征库
            load('mfcc_base.mat','mfcc','musicnames'); % 导入特征库
            mfcc_test = getmfcc(app.audiodata,app.fs);
            
            % 将例子与库中曲子进行mfcc比对
            for i=1:length(mfcc)
                for j=1:3
                    [dist{i,j}] = dtw_m(mfcc_test,mfcc{i,j});
                    % figure;
                    % plot(ix, iy);
                    % xlabel('test');
                    % ylabel(musicnames(i,1));
                    % title(['DTW匹配距离: ', num2str(dist{i,j})]);
                end
            end

            % 查找结果
            best = dist{1,1};
            for i=1:length(mfcc)
                for j = 1:3
                    if (dist{i,j} <= best)
                        best = dist{i,j};
                        index = i;
                    end
                end
                match_flag = 1;
            end

            app.matched_music = musicnames{index};

            matched_file = strcat('E:\xinhaodazuoye\Project\musicbase\',app.matched_music,'.mp3');
            [app.audiodata_processed,app.fs_processed] = audioread(matched_file);

            
            if match_flag == 1
                app.TextArea.Value = app.matched_music;% 显示结果
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if strcmp(value,'On')
                draw(app.UIAxes,app.audiodata,app.fs);
            else
                cla(app.UIAxes);
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if strcmp(value,'On')
                draw(app.UIAxes2,app.audiodata_processed,app.fs);
            else
                cla(app.UIAxes2);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %[y,fs]=audioread(app.music_path);
            y=app.audiodata_origin;
            x=y(:,1)';
            y1=lp(x,app.fs)';
            app.audiodata = x;
            app.audiodata_processed = y1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1)';
            y2=bp(x,app.fs);
            % app.audiodata = x;
            app.audiodata_processed = y2';
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1)';
            y3=hp(x,app.fs);
            % app.audiodata = x;
            app.audiodata_processed = y3';
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1)';
            y1=lp(x,app.fs);
            y2=bp(x,app.fs);
            y3=hp(x,app.fs);
            y4=(3*y1+y2+y3)/2;
            % app.audiodata = x;
            app.audiodata_processed = y4';
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1)';
            y1=lp(x,app.fs);
            y2=bp(x,app.fs);
            y3=hp(x,app.fs);
            y5=(y1+3*y2+y3)/2;
            % app.audiodata = x;
            app.audiodata_processed = y5';
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1)';
            y1=lp(x,app.fs);
            y2=bp(x,app.fs);
            y3=hp(x,app.fs);
            y6=(y1+y2+3*y3)/2;
            % app.audiodata = x;
            app.audiodata_processed = y6';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %如果播放器中没有音乐就读入数据，并设置时间
            if isempty(app.player) == 1
                app.player = audioplayer(app.audiodata_origin,app.fs); 
            else
                stop(app.player);
                app.player = audioplayer(app.audiodata_origin,app.fs); 
            end
            %按下播放按钮，图标变化
            if app.Button_7.Text == '播放处理前音频'
                %判断是播放还是继续
                if app.player.CurrentSample == 1
                    play(app.player);
                elseif app.player.CurrentSample ~= 1
                    resume(app.player);
                end
                app.Button_7.Text = '暂停处理前音频';
            else
                pause(app.player);
                app.Button_7.Text = '播放处理前音频';
            end  
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %如果播放器中没有音乐就读入数据，并设置时间
            if isempty(app.player) == 1
                app.player = audioplayer(app.audiodata_processed,app.fs); 
            else
                stop(app.player);
                app.player = audioplayer(app.audiodata_processed,app.fs); 
            end
            %按下播放按钮，图标变化
            if app.Button_8.Text == '播放处理后音频'
                %判断是播放还是继续
                if app.player.CurrentSample == 1
                    play(app.player);
                elseif app.player.CurrentSample ~= 1
                    resume(app.player);
                end
                app.Button_8.Text = '暂停处理后音频';
            else
                pause(app.player);
                app.Button_8.Text = '播放处理后音频';
            end  
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            %先判断是否有正在播放的音乐，有就改变播放速度,但是要注意不是累乘，所以要先还原再乘
            if isempty(app.player) ~= 1
                app.player.SampleRate = (app.player.SampleRate)/(app.speed);
                app.speed = str2num(strip(value,'right','x'));
                app.player.SampleRate = (app.player.SampleRate)*(app.speed);
            else
                errordlg('当前无正在播放的音乐！');
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            y=app.audiodata_origin;
            x=y(:,1);
            y0=flipud(x);
            %如果播放器中没有音乐就读入数据，并设置时间
            if isempty(app.player) == 1
                app.player = audioplayer(y0,app.fs); 
            else
                stop(app.player);
                app.player = audioplayer(y0,app.fs); 
            end
            %按下播放按钮，图标变化
            if app.Button_12.Text == '倒放'
                %判断是播放还是继续
                if app.player.CurrentSample == 1
                    play(app.player);
                elseif app.player.CurrentSample ~= 1
                    resume(app.player);
                end
                app.Button_12.Text = '暂停';
            else
                pause(app.player);
                app.Button_12.Text = '倒放';
            end  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '识别结果';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Jay chou.png');
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入歌曲文件';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '播放';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '识别';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '识别结果';
            app.TextArea = uitextarea(app.LeftPanel);
            app.Switch_3 = uiswitch(app.LeftPanel, 'rocker');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_4 = uiswitch(app.LeftPanel, 'rocker');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.CenterPanel);
            app.Panel.Title = '未经处理原音频';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [0 10000];
            app.UIAxes.XTick = [0 2000 4000 6000 8000];
            app.UIAxes.XTickLabel = {'0'; '2000'; '4000'; '6000'; '8000'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Panel_2 = uipanel(app.CenterPanel);
            app.Panel_2.Title = '处理后音频';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 8000];
            app.UIAxes2.YLim = [0 10000];
            app.RightPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'background.jpg');
            app.Button_4 = uibutton(app.RightPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '低通滤波';
            app.Button_5 = uibutton(app.RightPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '带通滤波';
            app.Button_6 = uibutton(app.RightPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '高通滤波';
            app.Button_7 = uibutton(app.RightPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放处理前音频';
            app.Button_8 = uibutton(app.RightPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '播放处理后音频';
            app.Button_9 = uibutton(app.RightPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '低音增强';
            app.Button_10 = uibutton(app.RightPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '突出人声';
            app.Button_11 = uibutton(app.RightPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '高音增强';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '倍速';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'0.5x', '0.75x', '1x', '1.5x', '2x'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1x';
            app.Button_12 = uibutton(app.RightPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '倒放';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end